classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        ConnexionPanel                 matlab.ui.container.Panel
        CommencerlalectureSwitchLabel  matlab.ui.control.Label
        Connect                        matlab.ui.control.Switch
        PortCOMDropDownLabel           matlab.ui.control.Label
        PortCOMDropDown                matlab.ui.control.DropDown
    end


    properties (Access = private)
        s % Serial port
        readLoop %
        valData % Données du tableau
        idInfo = {} % Données en fonction des ID
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.valData = {0,0,'RPM',0};


            %Ensemble des informations liées à chaque ID : %RPM %Unité %Range
            %Système
                app.idInfo(50,:) = {'RPM','Tour/min',10000};
                app.idInfo(54,:) = {'Pression huile','',0};
                app.idInfo(58,:) = {'Vitesse','Km/h',0};
                app.idInfo(62,:) = {'Température moteur','°C',0};
                app.idInfo(66,:) = {'Niveau essence','Pourcentage',0};
                app.idInfo(70,:) = {'Batterie','mV',0};
            %Temps
                app.idInfo(100,:) = {'Heure','s',0};
                app.idInfo(104,:) = {'Temps/tour','ms',0};
                app.idInfo(108,:) = {'Temps de session','s',0};
                
            %Lumières
                app.idInfo(120,:) = {'Rampe','',0};
                app.idInfo(124,:) = {'Warning','',0};
                app.idInfo(128,:) = {'Cligno gauche','',0};
                app.idInfo(132,:) = {'Cligno droit','',0};
            app.valData = {};                
           app.UITable.Data = app.valData; 
            % #1 Drop Down Menu
            app.PortCOMDropDown.Items = cellstr(seriallist);


        end

        % Value changed function: Connect
        function Connecting(app, event)
            value = app.Connect.Value;
            %disp(strcmp(value,'Off'))
            if strcmp(value,'Off')
                disp('Connexion série interrompue')
                app.readLoop = 0; % Désactiver la lecture
                
            else
                tic
                app.readLoop = 1;
                app.s = serial(app.PortCOMDropDown.Value);
                app.s.Baudrate = 115200;
                fopen(app.s)
                index =0;
                while 1
                    
                    busData  = str2num(fgetl(app.s)); % Récuperer ce qu'il y a sur le BUS série
                    %                     busData  = str2num(fgetl(s));
                    if ~isempty(busData) % Si la donnée du bus n'est pas vide
                        % busData(1) -> ID
                        % busData(2) -> Valeur
                        try
                            index = find([app.valData{:,2}] == busData(1)); % Cherche la ligne où se trouve l'ID
                        catch
                            % Si c'est la première fois qu'on fait la boucle: Initalisation
                            [index,~] = size(app.valData);
                            disp(index)
                            index = index + 1;
                        end 
                        
                        % Si l'ID est pas dans la matrice de  données -> initialiser
                        if  isempty(index)
                            [index,~] = size(app.valData);
                            index = index + 1;                            
                        end
                        
                        
%                         app.valData{index,1} = toc; %Timestamp
%                         app.valData{index,2} = busData(1); % ID
% 
%                         app.valData{index,3} = char(app.idInfo(busData(1),1)); % Nom de l'ID
%                         app.valData{index,4} = busData(2); % Valeur
%                         app.valData{index,5} = char(app.idInfo(busData(1),2)); % Unité
                        
                        % Rajouter / Modifier la ligner =
                        % Timestampe - ID - Nom de l'ID - Valeur - Unité
                        
                        app.valData(index,:) = {toc,busData(1),char(app.idInfo(busData(1),1)),busData(2),char(app.idInfo(busData(1),2))};
                        disp('-----------')
                        disp(app.valData)
                        
                        
                        disp('===========')
                        app.UITable.Data = app.valData;
                    end
                    pause(0.05)
                    if not(app.readLoop)
                        break
                    end

                end
                fclose(app.s)
                delete(app.s)
            
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            try
                fclose(app.s)
                delete(app.s)
                disp('Connexion série interrompue')
            end
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Timestamp'; 'ID'; 'Nom'; 'Value'; 'Unité'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false true];
            app.ConnexionPanel = uipanel(app.UIFigure);
            app.ConnexionPanel.Title = 'Connexion';
            app.CommencerlalectureSwitchLabel = uilabel(app.ConnexionPanel);
            app.CommencerlalectureSwitchLabel.Text = 'Commencer la lecture';
            app.Connect = uiswitch(app.ConnexionPanel, 'slider');
            app.Connect.ValueChangedFcn = createCallbackFcn(app, @Connecting, true);
            app.PortCOMDropDownLabel = uilabel(app.ConnexionPanel);
            app.PortCOMDropDownLabel.Text = 'Port COM';
            app.PortCOMDropDown = uidropdown(app.ConnexionPanel);
            app.PortCOMDropDown.Items = {};
            app.PortCOMDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end